classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TooglesPanel   matlab.ui.container.Panel
        t1SliderLabel  matlab.ui.control.Label
        t1Slider       matlab.ui.control.Slider
        t2SliderLabel  matlab.ui.control.Label
        t2Slider       matlab.ui.control.Slider
        l2SliderLabel  matlab.ui.control.Label
        l2Slider       matlab.ui.control.Slider
        Label          matlab.ui.control.Label
        Label_2        matlab.ui.control.Label
        Label_3        matlab.ui.control.Label
        PlotPanel      matlab.ui.container.Panel
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        theta1 = 0;
        theta2 = 0;
        h0 = 2;
        h1 = 2;
        h2 = 2;
        l2 = 3;

        b0 = NaN;
        b1 = NaN;
        b2 = NaN;

        H10 = NaN;
        H21 = NaN;
        H32 = NaN;
    end
    
    methods (Access = private)
        
        function plotFrame(T, varargin)
            if ~isempty(varargin)
                k = varargin{1};
            else
                k = 1;
            end
            x = [k 0 0 1]';
            y = [0 k 0 1]';
            z = [0 0 k 1]';

            Op = T * [0 0 0 1]';
            xp = T * x;
            yp = T * y;
            zp = T * z;

            plot3([Op(1) xp(1)], [Op(2) xp(2)], [Op(3) xp(3)], 'r', 'LineWidth', 2)
            % hold on
            plot3([Op(1) yp(1)], [Op(2) yp(2)], [Op(3) yp(3)], 'b', 'LineWidth', 2)
            plot3([Op(1) zp(1)], [Op(2) zp(2)], [Op(3) zp(3)], 'g', 'LineWidth', 2)
            % hold off
        end
        
        function setLimitsAndInitialValues(app)
            limit = [0, 5];
            app.t1Slider.Limits = [-pi, pi];
            app.t2Slider.Limits = [-pi, pi];
            app.l2Slider.Limits = limit;

            set(app.t1Slider, 'value', app.theta1);
            set(app.t2Slider, 'value', app.theta2);
            set(app.l2Slider, 'value', app.l2);

            set(app.Label, 'text', num2str(app.theta1));
            set(app.Label_2, 'text', num2str(app.theta2));
            set(app.Label_3, 'text', num2str(app.l2));

            app.b0 = [0 0 app.h0]';
            app.b1 = [0 0 app.h1]';
            app.b2 = [0 app.h2 app.l2]';

            app.H10 = [rotz(rad2deg(app.theta1)) app.b0;
                       zeros(1, 3) 1];
            app.H21 = [rotx(rad2deg(app.theta2)) app.b1;
                       zeros(1, 3) 1];
            app.H32 = [eye(3) app.b2;
                       zeros(1, 3) 1];
        end

        function plotRobot(app)
            ax = app.UIAxes;
            cla(ax);
            hold(ax, "on");
            plotFrame(eye(4), ax);
            plotFrame(app.H10, ax);
            plotFrame(app.H10*app.H21, ax);
            plotFrame(app.H10*app.H21*app.H32, ax);
            view(ax, 86, 26);
            axis(ax, [-10 10 -10 10 -10 10]);
            xlabel(ax, 'x');
            ylabel(ax, 'y');
            zlabel(ax, 'z');
            hold(ax, "off");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setLimitsAndInitialValues();
            app.plotRobot();
        end

        % Value changing function: t1Slider
        function t1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.theta1 = event.Value;
            set(app.Label, 'text', num2str(app.theta1));
            app.H10 = [rotz(rad2deg(app.theta1)) app.b0;
                       zeros(1, 3) 1];
            app.plotRobot();
            
        end

        % Value changed function: t2Slider
        function t2SliderValueChanged(app, event)
            value = app.t2Slider.Value;
            app.theta2 = event.Value;
            set(app.Label_2, 'text', num2str(app.theta2));
            app.H21 = [rotx(rad2deg(app.theta2)) app.b1;
                       zeros(1, 3) 1];
            app.plotRobot();
        end

        % Value changed function: l2Slider
        function l2SliderValueChanged(app, event)
            value = app.l2Slider.Value;
            app.l2 = event.Value;
            app.b2 = [0 app.h2 app.l2]';
            set(app.Label_3, 'text', num2str(app.l2));
            app.H32 = [eye(3) app.b2;
                       zeros(1, 3) 1];
            app.plotRobot();
        end

        % Value changing function: t2Slider
        function t2SliderValueChanging(app, event)
            changingValue = event.Value;
            value = app.l2Slider.Value;
            app.theta2 = event.Value;
            set(app.Label_2, 'text', num2str(app.theta2));
            app.H21 = [rotx(rad2deg(app.theta2)) app.b1;
                       zeros(1, 3) 1];
            app.plotRobot();
        end

        % Value changing function: l2Slider
        function l2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.l2 = event.Value;
            app.b2 = [0 app.h2 app.l2]';
            set(app.Label_3, 'text', num2str(app.l2));
            app.H32 = [eye(3) app.b2;
                       zeros(1, 3) 1];
            app.plotRobot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TooglesPanel = uipanel(app.UIFigure);
            app.TooglesPanel.Title = 'Toogles';
            app.t1SliderLabel = uilabel(app.TooglesPanel);
            app.t1SliderLabel.Text = {'t1'; ''};
            app.t1Slider = uislider(app.TooglesPanel);
            app.t1Slider.ValueChangingFcn = createCallbackFcn(app, @t1SliderValueChanging, true);
            app.t2SliderLabel = uilabel(app.TooglesPanel);
            app.t2SliderLabel.Text = 't2';
            app.t2Slider = uislider(app.TooglesPanel);
            app.t2Slider.ValueChangedFcn = createCallbackFcn(app, @t2SliderValueChanged, true);
            app.t2Slider.ValueChangingFcn = createCallbackFcn(app, @t2SliderValueChanging, true);
            app.l2SliderLabel = uilabel(app.TooglesPanel);
            app.l2SliderLabel.Text = 'l2';
            app.l2Slider = uislider(app.TooglesPanel);
            app.l2Slider.ValueChangedFcn = createCallbackFcn(app, @l2SliderValueChanged, true);
            app.l2Slider.ValueChangingFcn = createCallbackFcn(app, @l2SliderValueChanging, true);
            app.Label = uilabel(app.TooglesPanel);
            app.Label_2 = uilabel(app.TooglesPanel);
            app.Label_3 = uilabel(app.TooglesPanel);
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end