classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ParametersPanel       matlab.ui.container.Panel
        xSliderLabel          matlab.ui.control.Label
        xSlider               matlab.ui.control.Slider
        ySliderLabel          matlab.ui.control.Label
        ySlider               matlab.ui.control.Slider
        zSliderLabel          matlab.ui.control.Label
        zSlider               matlab.ui.control.Slider
        thetaSliderLabel      matlab.ui.control.Label
        thetaSlider           matlab.ui.control.Slider
        NotReachableCheckBox  matlab.ui.control.CheckBox
        PlotPanel             matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        l01 = 3;
        l12 = 3;
        l23 = 2;
        l34 = 2;
        l45 = 2;
        x = 5;
        y = 2;
        z = 3;
        theta = 0;
        P5 = zeros(3, 1);
        P4 = zeros(3, 1);
        P3 = zeros(3, 1);
        P2 = zeros(3, 1);
        P1 = zeros(3, 1);
        phi1 = 0;
        phi2 = 0;
        phi3 = 0;
        phi4 = 0;
    end
    
    methods (Access = private)
        function setLimits(app)
            max_reach = app.l01 + app.l12 + app.l23 + app.l34 + app.l45;

            app.thetaSlider.Limits = [-pi, pi];
            app.zSlider.Limits = [-max_reach, max_reach];
            app.ySlider.Limits = [-max_reach, max_reach];
            app.xSlider.Limits = [-max_reach, max_reach];
        end

        function matrix = DHMatrix(app, a, alpha, d, theta)
            % function to create the matrix from the DH table row
            matrix = [cos(theta) -sin(theta) 0 a;
                      sin(theta)*cos(alpha) cos(theta)*cos(alpha) -sin(alpha) -sin(alpha)*d;
                      sin(theta)*sin(alpha) cos(theta)*sin(alpha) cos(alpha) cos(alpha)*d;
                      0 0 0 1
                     ];
        end

        function getP4(app)
            app.phi1 = atan2(app.P5(2), app.P5(1));

            l45proj = app.l45*cos(app.theta);
            xproj = l45proj*cos(app.phi1);
            yproj = l45proj*sin(app.phi1);
            zproj = app.l45*sin(app.theta);

            app.P4 = [app.P5(1) - xproj;
                      app.P5(2) - yproj;
                      app.P5(3) - zproj;
                      1
                     ];        %% first we find P4;
        end

        function ik(app)
            if app.checkReachbility() == false
               app.P5(1) = 5;
               app.P5(2) = 2;
               app.P5(3) = 3;

               app.getP4();

               set(app.NotReachableCheckBox, 'value', true);
            else
               set(app.NotReachableCheckBox, 'value', false);
            end

            H10 = app.DHMatrix(0, 0, app.l01, app.phi1);
            H21 = app.DHMatrix(app.l12, pi/2, 0, 0); %% this is just the translation because IDK the phi2 angle right now
            
            newP4 = inv(H10*H21)*app.P4;
            newP4 = newP4(1:2);
            r = norm(newP4);

            c1 = (app.l23^2 + app.l34^2 - r^2)/(2*app.l23*app.l34);
            c2 = (r^2 + app.l23^2 - app.l34^2)/(2*app.l23*r);

            gamma = acos(c1);
            alpha = acos(c2);

            app.phi3 = pi - gamma;
            app.phi2 = atan2(newP4(2), newP4(1)) - alpha;
            app.phi4 = app.theta - app.phi2 - app.phi3;
       end

        function answ=checkReachbility(app)
            H10 = app.DHMatrix(0, 0, app.l01, app.phi1);
            H21 = app.DHMatrix(app.l12, pi/2, 0, 0); %% this is just the translation because IDK the phi2 angle right now
            
            newP4 = inv(H10*H21)*app.P4;
            newP4 = newP4(1:2);
            r = norm(newP4);

            c1 = (app.l23^2 + app.l34^2 - r^2)/(2*app.l23*app.l34);
            c2 = (r^2 + app.l23^2 - app.l34^2)/(2*app.l23*r);

            if c1 > 1 || c2 > 1
                answ = false;
            else
                answ = true;
            end
        end

        function plotFrame(app, T, varargin)
            if ~isempty(varargin)
                k = varargin{1};
            else
                k = 1;
            end

            tx = [k 0 0 1]';
            ty = [0 k 0 1]';
            tz = [0 0 k 1]';

            Op = T * [0 0 0 1]';
            xp = T * tx;
            yp = T * ty;
            zp = T * tz;
            
            plot3(app.UIAxes, [Op(1) xp(1)], [Op(2) xp(2)], [Op(3) xp(3)], 'r', 'LineWidth', 2);
            % hold on
            plot3(app.UIAxes, [Op(1) yp(1)], [Op(2) yp(2)], [Op(3) yp(3)], 'b', 'LineWidth', 2);
            plot3(app.UIAxes, [Op(1) zp(1)], [Op(2) zp(2)], [Op(3) zp(3)], 'g', 'LineWidth', 2);
            % hold off
        end

        function plotRobot(app)
            % plotting the reference frames
            H10 = app.DHMatrix(0, 0, app.l01, app.phi1);
            H21 = app.DHMatrix(app.l12, pi/2, 0, app.phi2);
            H32 = app.DHMatrix(app.l23, 0, 0, app.phi3);
            H43 = app.DHMatrix(app.l34, 0, 0, app.phi4);
            H54 = app.DHMatrix(app.l45, 0, 0, 0);

            fP0 = [0 0 0 1]';
            fP1 = H10*fP0;
            fP2 = H10*H21*fP0;
            fP3 = H10*H21*H32*fP0;
            fP4 = H10*H21*H32*H43*fP0;
            fP5 = H10*H21*H32*H43*H54*fP0;

            points = [fP0 fP1 fP2 fP3 fP4 fP5];

            cla(app.UIAxes);
            hold(app.UIAxes, "on");
            plot3(app.UIAxes, points(1, :), points(2, :), points(3, :), '-o');
            app.plotFrame(eye(4));
            % app.plotFrame(H10);
            % app.plotFrame(H10*H21);
            % app.plotFrame(H10*H21*H32);
            % app.plotFrame(H10*H21*H32*H43);
            app.plotFrame(H10*H21*H32*H43*H54);
            view(app.UIAxes, [-9 30]);
            xlabel(app.UIAxes, 'x');
            ylabel(app.UIAxes, 'y');
            zlabel(app.UIAxes, 'z');
            hold(app.UIAxes, "off");
        end

        function updatePlot(app)
            app.getP4();
            app.ik();
            app.plotRobot();
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setLimits();
            app.P5(1) = app.x;
            app.P5(2) = app.y;
            app.P5(3) = app.z;

            set(app.thetaSlider, 'value', 0);
            set(app.xSlider, 'value', 5);
            set(app.ySlider, 'value', 2);
            set(app.zSlider, 'value', 3);

            app.updatePlot();
        end

        % Value changing function: xSlider
        function xSliderValueChanging(app, event)
            changingValue = event.Value;
            app.P5(1) = app.xSlider.Value;
            app.updatePlot();
        end

        % Value changing function: ySlider
        function ySliderValueChanging(app, event)
            changingValue = event.Value;
            app.P5(2) = app.ySlider.Value;
            app.updatePlot();            
        end

        % Value changed function: zSlider
        function zSliderValueChanged(app, event)
            value = app.zSlider.Value;
            app.P5(3) = app.zSlider.Value;
            app.updatePlot();
        end

        % Value changing function: zSlider
        function zSliderValueChanging(app, event)
            changingValue = event.Value;
            app.P5(3) = app.zSlider.Value;
            app.updatePlot();            
        end

        % Value changing function: thetaSlider
        function thetaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.theta = app.thetaSlider.Value;
            app.updatePlot();
        end

        % Value changed function: thetaSlider
        function thetaSliderValueChanged(app, event)
            value = app.thetaSlider.Value;
            changingValue = event.Value;
            app.theta = app.thetaSlider.Value;
            app.updatePlot();
        end

        % Value changed function: ySlider
        function ySliderValueChanged(app, event)
            value = app.ySlider.Value;
            app.P5(2) = app.ySlider.Value;
            app.updatePlot();    
        end

        % Value changed function: xSlider
        function xSliderValueChanged(app, event)
            value = app.xSlider.Value;
            app.P5(1) = app.xSlider.Value;
            app.updatePlot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.xSliderLabel = uilabel(app.ParametersPanel);
            app.xSliderLabel.Text = 'x';
            app.xSlider = uislider(app.ParametersPanel);
            app.xSlider.ValueChangedFcn = createCallbackFcn(app, @xSliderValueChanged, true);
            app.xSlider.ValueChangingFcn = createCallbackFcn(app, @xSliderValueChanging, true);
            app.ySliderLabel = uilabel(app.ParametersPanel);
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.ParametersPanel);
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @ySliderValueChanged, true);
            app.ySlider.ValueChangingFcn = createCallbackFcn(app, @ySliderValueChanging, true);
            app.zSliderLabel = uilabel(app.ParametersPanel);
            app.zSliderLabel.Text = 'z';
            app.zSlider = uislider(app.ParametersPanel);
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @zSliderValueChanged, true);
            app.zSlider.ValueChangingFcn = createCallbackFcn(app, @zSliderValueChanging, true);
            app.thetaSliderLabel = uilabel(app.ParametersPanel);
            app.thetaSliderLabel.Text = '\theta';
            app.thetaSlider = uislider(app.ParametersPanel);
            app.thetaSlider.ValueChangedFcn = createCallbackFcn(app, @thetaSliderValueChanged, true);
            app.thetaSlider.ValueChangingFcn = createCallbackFcn(app, @thetaSliderValueChanging, true);
            app.NotReachableCheckBox = uicheckbox(app.ParametersPanel);
            app.NotReachableCheckBox.Text = 'Not Reachable';
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end