classdef es_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        PlotPanel            matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
        TogglesPanel         matlab.ui.container.Panel
        IKcalculationButton  matlab.ui.control.Button
        xSliderLabel         matlab.ui.control.Label
        xSlider              matlab.ui.control.Slider
        ySliderLabel         matlab.ui.control.Label
        ySlider              matlab.ui.control.Slider
        zSliderLabel         matlab.ui.control.Label
        zSlider              matlab.ui.control.Slider
        thetaLabel           matlab.ui.control.Label
        thetaSlider          matlab.ui.control.Slider
        phiLabel             matlab.ui.control.Label
        phiSlider            matlab.ui.control.Slider
        Label                matlab.ui.control.Label
        Label_2              matlab.ui.control.Label
        Label_3              matlab.ui.control.Label
        Label_4              matlab.ui.control.Label
        Label_5              matlab.ui.control.Label
        Label_6              matlab.ui.control.Label
        psiSliderLabel       matlab.ui.control.Label
        psiSlider            matlab.ui.control.Slider
    end

    
    properties (Access = private)
        l = [2; 2; 2; 2; 2];
        PE = [4; 4; 4];
        theta = 0;
        phi = 0;
        psi = 0;

        H10 = NaN;
        H21 = NaN;
        H32 = NaN;
        H43 = NaN;
        H54 = NaN;
        H65 = NaN;
        H76 = NaN;

        phis = zeros(6, 1);

        ikbutton = NaN;
    end
    
    methods (Access = private)
        
        function setLimitsAndInitValues(app)
            lim = sum(app.l);
            app.xSlider.Limits = [-lim, lim];
            app.ySlider.Limits = [-lim, lim];
            app.zSlider.Limits = [-lim, lim];
            app.phiSlider.Limits = [-pi, pi];
            app.thetaSlider.Limits = [-pi, pi];
            app.psiSlider.Limits = [-pi, pi]

            set(app.xSlider, 'value', app.PE(1));
            set(app.ySlider, 'value', app.PE(2));
            set(app.zSlider, 'value', app.PE(3));
            set(app.thetaSlider, 'value', app.theta);
            set(app.phiSlider, 'value', app.phi);
            set(app.psiSlider, 'value', app.psi);
        end

        function PW=getPW(app, R06, PE)
            PW = PE - app.l(5)*R06*[0; 0; 1];
        end

        function res=ik(app, PE)
            l01 = app.l(1);
            l12 = app.l(2);
            l23 = app.l(3);
            l3W = app.l(4);
            lWE = app.l(5);

            R06 = eul2rotm([app.theta, app.phi, app.psi], "ZYX");
            PW = app.getPW(R06, PE);
            phi1 = atan2(PW(2), PW(1));
            
            app.H10 = DHMatrix(0, 0, l01, phi1);
            app.H21 = DHMatrix(l12, pi/2, 0, 0); %% this is just the translation because IDK the phi2 angle right now

            newPW = inv(app.H10*app.H21)*[PW; 1];
            newPW = newPW(1:2);
            r = norm(newPW);
            
            c1 = (l23^2 + l3W^2 - r^2)/(2*l23*l3W);
            c2 = (r^2 + l23^2 - l3W^2)/(2*l23*r);

            if (c1 > 1) || (c2 > 1)
                res = false;
            else
                gamma = acos(c1);
                alpha = acos(c2);
            
                phi3 = pi - gamma;
                phi2 = atan2(newPW(2), newPW(1)) - alpha;
    
                app.H10 = DHMatrix(0, 0, l01, phi1);
                app.H21 = DHMatrix(l12, pi/2, 0, phi2);
                app.H32 = DHMatrix(l23, 0, 0, phi3);
                
                H03 = app.H10*app.H21*app.H32;
                R03 = H03(1:3, 1:3);
                
                R36 = R03'*R06;
    
                phi4 = atan2(R36(3, 3), R36(2, 3));
                phi5 = atan2(norm(R36(2:3, 3)), R36(1, 3));
                phi6 = atan2(R36(1,1), -R36(1, 2));
    
                app.H43 = DHMatrix(l3W, pi/2, 0, phi4);
                app.H54 = DHMatrix(0, -pi/2, 0, phi5);
                app.H65 = DHMatrix(lWE, phi6, 0, 0);
                app.H76 = [roty(rad2deg(pi/2)) zeros(3, 1);
                           zeros(1, 3) 1
                          ];
    
                app.phis = [phi1; phi2; phi3; phi4; phi5; phi6];

                res = true;
            end
        end

        function plotRobot(app)
            hold(app.UIAxes, "on");
            cla(app.UIAxes);

            fP0 = [0 0 0 1]';
            fP1 = app.H10*fP0;
            fP2 = app.H10*app.H21*fP0;
            fP3 = app.H10*app.H21*app.H32*fP0;
            fP4 = app.H10*app.H21*app.H32*app.H43*fP0;
            fP5 = app.H10*app.H21*app.H32*app.H43*app.H54*fP0;
            fP6 = app.H10*app.H21*app.H32*app.H43*app.H54*app.H65*app.H76*fP0;
            
            points = [fP0, fP1, fP2, fP3, fP4, fP5, fP6];
            
            plotFrame(eye(4), app.UIAxes);
            % plotFrame(app.H10, app.UIAxes);
            % plotFrame(app.H10*app.H21, app.UIAxes);
            % plotFrame(app.H10*app.H21*app.H32, app.UIAxes);
            % plotFrame(app.H10*app.H21*app.H32*app.H43, app.UIAxes);
            % plotFrame(app.H10*app.H21*app.H32*app.H43*app.H54, app.UIAxes);
            plotFrame(app.H10*app.H21*app.H32*app.H43*app.H54*app.H65*app.H76, app.UIAxes);

            plot3(app.UIAxes, points(1, :), points(2, :), points(3, :), '--o','Color','b');
            view(app.UIAxes, [-9 30]);
            xlabel(app.UIAxes, 'x');
            ylabel(app.UIAxes, 'y');
            zlabel(app.UIAxes, 'z');

            hold(app.UIAxes, "off");
        end

        function toggleButton(app, toggle)
            if toggle
                set(app.IKcalculationButton, 'Enable', 'on');
            else
                set(app.IKcalculationButton, 'Enable', 'off');
            end
        end

        function setLabels(app)
            set(app.Label, 'text', num2str(app.PE(1)));
            set(app.Label_2, 'text', num2str(app.PE(2)));
            set(app.Label_3, 'text', num2str(app.PE(3)));
            set(app.Label_4, 'text', num2str(app.theta));
            set(app.Label_5, 'text', num2str(app.phi));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setLimitsAndInitValues();
            app.ikbutton = app.ik(app.PE);
            app.toggleButton(app.ikbutton);
            app.plotRobot();
            app.setLabels();
        end

        % Button pushed function: IKcalculationButton
        function IKcalculationButtonPushed(app, event)
            app.plotRobot();
        end

        % Value changed function: xSlider
        function xSliderValueChanged(app, event)
           app.PE(1) = app.xSlider.Value;
           app.ikbutton = app.ik(app.PE);
           app.toggleButton(app.ikbutton);
           app.setLabels();
        end

        % Value changed function: ySlider
        function ySliderValueChanged(app, event)
           app.PE(2) = app.ySlider.Value;
           app.ikbutton = app.ik(app.PE);
           app.toggleButton(app.ikbutton);
           app.setLabels();
        end

        % Value changed function: zSlider
        function zSliderValueChanged(app, event)
           app.PE(3) = app.zSlider.Value;
           app.ikbutton = app.ik(app.PE);
           app.toggleButton(app.ikbutton);
           app.setLabels();
        end

        % Value changed function: thetaSlider
        function thetaSliderValueChanged(app, event)
           app.theta = app.thetaSlider.Value;
           app.ikbutton = app.ik(app.PE);
           app.toggleButton(app.ikbutton);
           app.setLabels();
        end

        % Value changed function: phiSlider
        function phiSliderValueChanged(app, event)
           app.phi = app.phiSlider.Value;
           app.ikbutton = app.ik(app.PE);
           app.toggleButton(app.ikbutton);
           app.setLabels();
        end

        % Value changed function: psiSlider
        function psiSliderValueChanged(app, event)
           app.psi = app.psiSlider.Value;
           app.ikbutton = app.ik(app.PE);
           app.toggleButton(app.ikbutton);
           app.setLabels();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TogglesPanel = uipanel(app.UIFigure);
            app.TogglesPanel.Title = 'Toggles';
            app.IKcalculationButton = uibutton(app.TogglesPanel, 'push');
            app.IKcalculationButton.ButtonPushedFcn = createCallbackFcn(app, @IKcalculationButtonPushed, true);
            app.IKcalculationButton.Text = 'IK calculation';
            app.xSliderLabel = uilabel(app.TogglesPanel);
            app.xSliderLabel.Text = 'x';
            app.xSlider = uislider(app.TogglesPanel);
            app.xSlider.ValueChangedFcn = createCallbackFcn(app, @xSliderValueChanged, true);
            app.ySliderLabel = uilabel(app.TogglesPanel);
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.TogglesPanel);
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @ySliderValueChanged, true);
            app.zSliderLabel = uilabel(app.TogglesPanel);
            app.zSliderLabel.Text = 'z';
            app.zSlider = uislider(app.TogglesPanel);
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @zSliderValueChanged, true);
            app.thetaLabel = uilabel(app.TogglesPanel);
            app.thetaLabel.Text = 'theta';
            app.thetaSlider = uislider(app.TogglesPanel);
            app.thetaSlider.ValueChangedFcn = createCallbackFcn(app, @thetaSliderValueChanged, true);
            app.phiLabel = uilabel(app.TogglesPanel);
            app.phiLabel.Text = 'phi';
            app.phiSlider = uislider(app.TogglesPanel);
            app.phiSlider.ValueChangedFcn = createCallbackFcn(app, @phiSliderValueChanged, true);
            app.Label = uilabel(app.TogglesPanel);
            app.Label_2 = uilabel(app.TogglesPanel);
            app.Label_3 = uilabel(app.TogglesPanel);
            app.Label_4 = uilabel(app.TogglesPanel);
            app.Label_5 = uilabel(app.TogglesPanel);
            app.Label_6 = uilabel(app.TogglesPanel);
            app.psiSliderLabel = uilabel(app.TogglesPanel);
            app.psiSliderLabel.Text = 'psi';
            app.psiSlider = uislider(app.TogglesPanel);
            app.psiSlider.ValueChangedFcn = createCallbackFcn(app, @psiSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = es_5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end